classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        clickenhanceimageandwaitforprocessLabel_2  matlab.ui.control.Label
        StopVideoButton_2             matlab.ui.control.Button
        PlayVideoButton_2             matlab.ui.control.Button
        EnhanceVideoButton            matlab.ui.control.Button
        StopVideoButton               matlab.ui.control.Button
        PlayVideoButton               matlab.ui.control.Button
        videoperformancemetricsLabel  matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        TextArea_2                    matlab.ui.control.TextArea
        ImageperformancemetricsLabel  matlab.ui.control.Label
        LoadVideoButton               matlab.ui.control.Button
        clickenhanceimageandwaitforprocessLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        EnhanceImageButton            matlab.ui.control.Button
        LoadimageButton               matlab.ui.control.Button
        UnderwaterVideoEnhancementLabel  matlab.ui.control.Label
        UnderwaterImageenhancementLabel  matlab.ui.control.Label
        OutputTextArea                matlab.ui.control.TextArea
        LoadedimageLabel              matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ImportedImage % Description
        FirstFrame % Description
        VideoObject
        IsPlaying = false
        LoadedVideoPath % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
[file,path] = uigetfile(filterspec); 
imagefile = [path file];
im = imread(imagefile);
imagesc(app.UIAxes2,im);
app.ImportedImage = im;
imwrite(im, 'original_input.jpg');
im2 = double(app.ImportedImage) / 255;
% Validate input
if size(im2, 3) ~= 3
    app.OutputTextArea.Value = "error:Image must be RGB.";
end
avg_rgb = mean(mean(im2, 1), 2);
brightness = mean(rgb2gray(im2), 'all');
if avg_rgb(3) > avg_rgb(2) && avg_rgb(3) > avg_rgb(1) % Blue dominates
    if brightness < 0.3
        category = 'Low-Light Bluish';
    else
        category = 'Bluish Hazy';
    end
elseif avg_rgb(2) > avg_rgb(3) && avg_rgb(2) > avg_rgb(1) % Green dominates
    if brightness < 0.3
        category = 'Low-Light Greenish';
    else
        category = 'Greenish Hazy';
    end
else
    category = 'Greenish Hazy'; % Default
end
app.TextArea.Value = sprintf('Detected Category: %s', category);
        end

        % Button pushed function: EnhanceImageButton
        function EnhanceImageButtonPushed(app, event)
im2 = double(app.ImportedImage) / 255;
% Validate input
if size(im2, 3) ~= 3
    error('Image must be RGB.');
end
            %% Step 1: Category Detection and Dehazing
% Detect dominant color and brightness for category
avg_rgb = mean(mean(im2, 1), 2);
brightness = mean(rgb2gray(im2), 'all');
if avg_rgb(3) > avg_rgb(2) && avg_rgb(3) > avg_rgb(1) % Blue dominates
    if brightness < 0.3
        category = 'Low-Light Bluish';
    else
        category = 'Bluish Hazy';
    end
elseif avg_rgb(2) > avg_rgb(3) && avg_rgb(2) > avg_rgb(1) % Green dominates
    if brightness < 0.3
        category = 'Low-Light Greenish';
    else
        category = 'Greenish Hazy';
    end
else
    category = 'Greenish Hazy'; % Default
end
% Dehazing with Dark Channel Prior
dark_channel = min(im2, [], 3);
dark_channel = imfilter(dark_channel, ones(15,15)/225, 'replicate');
transmission = 1 - 0.7 * dark_channel; % Adjusted for underwater
transmission = max(0.1, transmission); % Minimum transmission
I_dehazed = zeros(size(im2));
for c = 1:3
    I_dehazed(:,:,c) = (im2(:,:,c) - 0.1) ./ transmission;
end
I_dehazed = max(0, min(1, I_dehazed));
%% Split channels for white balance
Ir = I_dehazed(:,:,1);
Ig = I_dehazed(:,:,2);
Ib = I_dehazed(:,:,3);

Ir_mean = mean(Ir, 'all');
Ig_mean = mean(Ig, 'all');
Ib_mean = mean(Ib, 'all');
%% Color compensation with category tuning
alpha = 0.1;
switch category
    case 'Bluish Hazy'
        Irc = Ir + alpha * (Ig_mean - Ir_mean); % Boost red indirectly
        Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
    case 'Greenish Hazy'
        Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.2; % Boost red
        Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
    case 'Low-Light Bluish'
        Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.5; % Boost illumination
        Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
    case 'Low-Light Greenish'
        Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.5; % Boost illumination
        Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
end
%% White Balance
I = cat(3, Irc, Ig, Ibc);
I_lin = rgb2lin(I);
percentiles = 5;
illuminant = illumgray(I_lin, percentiles);
I_lin = chromadapt(I_lin, illuminant, 'ColorSpace', 'linear-rgb');
Iwb = lin2rgb(I_lin);
%%% Multi-Scale Fusion with Attention %%%

%% Gamma Correction
gamma = 1.5; % Adjusted for better SSIM alignment
Igamma = imadjust(Iwb, [], [], gamma);
%% Gamma Correction
Igamma = imadjust(Iwb, [], [], 2);
%% Image Sharpening with Category Tuning
sigma = 20;
Igauss = Iwb;
N = 30;
for iter = 1:N
    Igauss = imgaussfilt(Igauss, sigma);
    Igauss = min(Iwb, Igauss);
end
gain = 1;
switch category
    case {'Bluish Hazy', 'Greenish Hazy'}
        gain = 0.9; % Reduce gain for hazy images
    case {'Low-Light Bluish', 'Low-Light Greenish'}
        gain = 1.1; % Increase gain for low-light images
end
Norm = (Iwb - gain * Igauss);
for n = 1:3
    Norm(:,:,n) = histeq(Norm(:,:,n));
end
Isharp = (Iwb + Norm) / 2;
%% Weights Calculation
Isharp_lab = rgb2lab(Isharp);
Igamma_lab = rgb2lab(Igamma);
%% Attention Mechanism
% Compute saliency maps for both inputs
WS1 = saliency_detection(Isharp);
WS1 = WS1 / max(WS1, [], 'all'); % Normalize
WS2 = saliency_detection(Igamma);
WS2 = WS2 / max(WS2, [], 'all'); % Normalize

% Convert to Lab for contrast and saturation
Isharp_lab = rgb2lab(Isharp);
Igamma_lab = rgb2lab(Igamma);

% Input 1 (Sharpened): Contrast and Saturation
R1 = double(Isharp_lab(:, :, 1)) / 255;
WC1 = sqrt((((Isharp(:,:,1)) - R1).^2 + ((Isharp(:,:,2)) - R1).^2 + ((Isharp(:,:,3)) - R1).^2) / 3);
WSAT1 = sqrt(1/3 * ((Isharp_lab(:,:,2)).^2 + (Isharp_lab(:,:,3)).^2)); % Saturation from a, b channels
WSAT1 = WSAT1 / max(WSAT1, [], 'all'); % Normalize

% Input 2 (Gamma-corrected): Contrast and Saturation
R2 = double(Igamma_lab(:, :, 1)) / 255;
WC2 = sqrt((((Igamma(:,:,1)) - R2).^2 + ((Igamma(:,:,2)) - R2).^2 + ((Igamma(:,:,3)) - R2).^2) / 3);
WSAT2 = sqrt(1/3 * ((Igamma_lab(:,:,2)).^2 + (Igamma_lab(:,:,3)).^2)); % Saturation from a, b channels
WSAT2 = WSAT2 / max(WSAT2, [], 'all'); % Normalize

% Attention Map: Combine saliency, contrast, and saturation
attention1 = WS1 .* WC1 .* WSAT1; % Attention for sharpened input
attention2 = WS2 .* WC2 .* WSAT2; % Attention for gamma-corrected input
attention1 = attention1 / max(attention1, [], 'all'); % Normalize
attention2 = attention2 / max(attention2, [], 'all'); % Normalize
%figure(6); imshow([attention1, attention2]); title('Attention Maps: Sharpened | Gamma-Corrected');

% Normalized weights with attention
W1 = (WC1 + WS1 + WSAT1 + attention1 + 0.1) ./ (WC1 + WS1 + WSAT1 + attention1 + WC2 + WS2 + WSAT2 + attention2 + 0.2);
W2 = (WC2 + WS2 + WSAT2 + attention2 + 0.1) ./ (WC1 + WS1 + WSAT1 + attention1 + WC2 + WS2 + WSAT2 + attention2 + 0.2);

%% Multi-Scale Fusion
img1 = Isharp;
img2 = Igamma;

level = 10;
Weight1 = gaussian_pyramid(W1, level);
Weight2 = gaussian_pyramid(W2, level);

% Laplacian pyramid for input 1
R1 = laplacian_pyramid(img1(:,:,1), level);
G1 = laplacian_pyramid(img1(:,:,2), level);
B1 = laplacian_pyramid(img1(:,:,3), level);

% Laplacian pyramid for input 2
R2 = laplacian_pyramid(img2(:,:,1), level);
G2 = laplacian_pyramid(img2(:,:,2), level);
B2 = laplacian_pyramid(img2(:,:,3), level);

% Fusion with attention-guided weights
for k = 1:level
    Rr{k} = Weight1{k} .* R1{k} + Weight2{k} .* R2{k};
    Rg{k} = Weight1{k} .* G1{k} + Weight2{k} .* G2{k};
    Rb{k} = Weight1{k} .* B1{k} + Weight2{k} .* B2{k};
end

% Reconstruct
R = pyramid_reconstruct(Rr);
G = pyramid_reconstruct(Rg);
B = pyramid_reconstruct(Rb);
fusion = cat(3, R, G, B);
fusion = max(0, min(1, fusion)); % Clip to valid range

% Post-processing for reference alignment
fusion = imadjust(fusion, [min(fusion(:)); max(fusion(:))], [0; 1]); % Contrast adjustment
% Step 2: Fine-tune color balance with minimal adjustment
mean_rgb = mean(mean(fusion, 1), 2); % Compute mean RGB values
color_adjust = 0.01; % Further reduced to minimize distortion
fusion(:,:,1) = fusion(:,:,1) + color_adjust * (mean_rgb(2) - mean_rgb(1)); % Adjust red
fusion(:,:,2) = fusion(:,:,2) + color_adjust * (mean_rgb(3) - mean_rgb(2)); % Adjust green
fusion(:,:,3) = fusion(:,:,3) + color_adjust * (mean_rgb(1) - mean_rgb(3)); % Adjust blue
fusion = max(0, min(1, fusion)); % Clip to valid range
% figure(7); imshow(fusion); title('Refined Color Enhanced Image');

%% Improved Contrast Enhancement with Enhanced Noise Management
% Apply stronger Gaussian blur to reduce noise
fusion = imgaussfilt(fusion, 0.7); % Increased sigma to 0.7 for better noise reduction

% Convert to HSV for contrast enhancement
fusion_hsv = rgb2hsv(fusion);
h = fusion_hsv(:,:,1); % Hue
S = fusion_hsv(:,:,2); % Saturation
V = fusion_hsv(:,:,3); % Value

% Apply a very soft Laplacian filter to minimize edge artifacts
H = [0 -0.25 0; -0.25 1 -0.25; 0 -0.25 0]; % Even softer Laplacian kernel
filter1 = imfilter(V, H, 'replicate'); % Apply to V
filter2 = imfilter(S, H, 'replicate'); % Apply to S
combined_VS = cat(2, filter1, filter2);
% figure(8); imshow(combined_VS); title('Laplacian Filter Output (V | S)');

% Compute local variance for V and S over a 3x3 window
window_size = 3;
varience_value = zeros(size(V));
varience_saturation = zeros(size(S));
V_diff = V - filter1;
S_diff = S - filter2;
for i = 1:size(V, 1) - window_size + 1
    for j = 1:size(V, 2) - window_size + 1
        V_window = V_diff(i:i+window_size-1, j:j+window_size-1);
        S_window = S_diff(i:i+window_size-1, j:j+window_size-1);
        varience_value(i,j) = mean(V_window(:).^2); % Local variance for V
        varience_saturation(i,j) = mean(S_window(:).^2); % Local variance for S
    end
end
% Pad the remaining pixels
varience_value(end-window_size+2:end, :) = repmat(varience_value(end-window_size+1, :), window_size-1, 1);
varience_value(:, end-window_size+2:end) = repmat(varience_value(:, end-window_size+1), 1, window_size-1);
varience_saturation(end-window_size+2:end, :) = repmat(varience_saturation(end-window_size+1, :), window_size-1, 1);
varience_saturation(:, end-window_size+2:end) = repmat(varience_saturation(:, end-window_size+1), 1, window_size-1);

% figure(9); imshow(varience_value); title('Local Variance of V Component');
% figure(10); imshow(varience_saturation); title('Local Variance of S Component');

% Compute adjusted V and S components
try1 = V - varience_value; % Adjusted V
try2 = S - varience_saturation; % Adjusted S
% figure(11); imshow(try1); title('Adjusted V Component');
% figure(12); imshow(try2); title('Adjusted S Component');

% Compute correlation coefficient
epsilon = 1e-10; % Avoid division by zero
Correlation = (try1 .* try2) ./ sqrt((varience_value + epsilon) .* (varience_saturation + epsilon));
% figure(13); imshow(Correlation); title('Correlation Coefficient');

% Compute enhancement value with minimal intensity
mean_V = mean(V(:));
std_V = std(V(:));
k1 = 0.2 + (1 - mean_V) * 0.1; % Further reduced enhancement
k2 = 0.1 + (std_V / 4); % Further reduced adjustment
fprintf('Adaptive k1: %.2f, k2: %.2f\n', k1, k2);

V_enhancement = V + k1 .* try1 - (k2 .* try2) .* Correlation;
S_enhancement = S + (k1 / 4) .* try2; % Very minimal saturation enhancement
V_enhancement = max(0, min(1, V_enhancement)); % Clip to valid range
S_enhancement = max(0, min(1, S_enhancement));
% figure(14); imshow(V_enhancement); title('Enhanced V Component');

% Apply Adaptive Histogram Equalization (CLAHE) with lower clip limit
num_tiles = [3 3];
clip_limit = 0.003 + (1 - mean_V) * 0.005; % Further reduced clip limit
V_clahe = adapthisteq(V_enhancement, 'NumTiles', num_tiles, 'ClipLimit', clip_limit);
% figure(15); imshow(V_clahe); title('CLAHE Enhanced V Component');

% Perform adaptive gamma correction with conservative stretching
mean_V_clahe = mean(V_clahe(:));
gamma_contrast = 0.95 + (1 - mean_V_clahe) * 0.05; % Even more conservative gamma
contrast_stretching = V_clahe .^ gamma_contrast;
fprintf('Adaptive gamma for contrast: %.2f\n', gamma_contrast);
% figure(16); imshow(contrast_stretching); title('After Gamma Correction');

% Convert back to RGB
final_output = cat(3, h, S_enhancement, contrast_stretching);
final_output_rgb = hsv2rgb(final_output);
%imwrite(final_output_rgb, 'enhanced_final_refined.jpg');
%%
function sm = saliency_detection(img)
%
%---------------------------------------------------------
% Read image and blur it with a 3x3 or 5x5 Gaussian filter
%---------------------------------------------------------
%img = imread('input_image.jpg');%Provide input image path
gfrgb = imfilter(img, fspecial('gaussian', 3, 3), 'symmetric', 'conv');
%---------------------------------------------------------
% Perform sRGB to CIE Lab color space conversion (using D65)
%---------------------------------------------------------
%cform = makecform('srgb2lab', 'whitepoint', whitepoint('d65'));
cform = makecform('srgb2lab');
lab = applycform(gfrgb,cform);
%---------------------------------------------------------
% Compute Lab average values (note that in the paper this
% average is found from the unblurred original image, but
% the results are quite similar)
%---------------------------------------------------------
l = double(lab(:,:,1)); lm = mean(mean(l));
a = double(lab(:,:,2)); am = mean(mean(a));
b = double(lab(:,:,3)); bm = mean(mean(b));
%---------------------------------------------------------
% Finally compute the saliency map and display it.
%---------------------------------------------------------
sm = (l-lm).^2 + (a-am).^2 + (b-bm).^2;
%imshow(sm,[]);
%---------------------------------------------------------
end
% Input 1 (Sharpened)
R1 = double(Isharp_lab(:, :, 1)) / 255;
WC1 = sqrt((((Isharp(:,:,1)) - R1).^2 + ((Isharp(:,:,2)) - R1).^2 + ((Isharp(:,:,3)) - R1).^2) / 3);
WS1 = saliency_detection(Isharp);
WS1 = WS1 / max(WS1, [], 'all');
WSAT1 = sqrt(1/3 * ((Isharp(:,:,1)-R1).^2 + (Isharp(:,:,2)-R1).^2 + (Isharp(:,:,3)-R1).^2));

% Input 2 (Gamma-corrected)
R2 = double(Igamma_lab(:, :, 1)) / 255;
WC2 = sqrt((((Igamma(:,:,1)) - R2).^2 + ((Igamma(:,:,2)) - R2).^2 + ((Igamma(:,:,3)) - R2).^2) / 3);
WS2 = saliency_detection(Igamma);
WS2 = WS2 / max(WS2, [], 'all');
WSAT2 = sqrt(1/3 * ((Igamma(:,:,1)-R2).^2 + (Igamma(:,:,2)-R2).^2 + (Igamma(:,:,3)-R2).^2));

% Normalized weights
W1 = (WC1 + WS1 + WSAT1 + 0.1) ./ (WC1 + WS1 + WSAT1 + WC2 + WS2 + WSAT2 + 0.2);
W2 = (WC2 + WS2 + WSAT2 + 0.1) ./ (WC1 + WS1 + WSAT1 + WC2 + WS2 + WSAT2 + 0.2);
%% Multi-Scale Fusion
img1 = Isharp;
img2 = Igamma;

%%
function out = gaussian_pyramid(img, level)
h = 1/16* [1, 4, 6, 4, 1];
filt = h'*h;
out{1} = imfilter(img, filt, 'replicate', 'conv');
temp_img = img;
for i = 2 : level
    temp_img = temp_img(1 : 2 : end, 1 : 2 : end);
    out{i} = imfilter(temp_img, filt, 'replicate', 'conv');
end
end
%%
level = 10;
Weight1 = gaussian_pyramid(W1, level);
Weight2 = gaussian_pyramid(W2, level);

%%
function out = laplacian_pyramid(img, level)
h = 1/16* [1, 4, 6, 4, 1];
%filt = h'*h;
out{1} = img;
temp_img = img;
for i = 2 : level
    temp_img = temp_img(1 : 2 : end, 1 : 2 : end);
    %out{i} = imfilter(temp_img, filt, 'replicate', 'conv');
    out{i} = temp_img;
end
% calculate the DoG
for i = 1 : level - 1
    [m, n] = size(out{i});
    out{i} = out{i} - imresize(out{i+1}, [m, n]);
end
end
%%
% Laplacian pyramid for input 1
R1 = laplacian_pyramid(img1(:,:,1), level);
G1 = laplacian_pyramid(img1(:,:,2), level);
B1 = laplacian_pyramid(img1(:,:,3), level);

% Laplacian pyramid for input 2
R2 = laplacian_pyramid(img2(:,:,1), level);
G2 = laplacian_pyramid(img2(:,:,2), level);
B2 = laplacian_pyramid(img2(:,:,3), level);

% Fusion
for k = 1:level
    Rr{k} = Weight1{k} .* R1{k} + Weight2{k} .* R2{k};
    Rg{k} = Weight1{k} .* G1{k} + Weight2{k} .* G2{k};
    Rb{k} = Weight1{k} .* B1{k} + Weight2{k} .* B2{k};
end

%%
function out = pyramid_reconstruct(pyramid)
level = length(pyramid);
for i = level : -1 : 2
    %temp_pyramid = pyramid{i};
    [m, n] = size(pyramid{i - 1});
    %out = pyramid{i - 1} + imresize(temp_pyramid, [m, n]);
    pyramid{i - 1} = pyramid{i - 1} + imresize(pyramid{i}, [m, n]);
end
out = pyramid{1};
end
%%
% Reconstruct
R = pyramid_reconstruct(Rr);
G = pyramid_reconstruct(Rg);
B = pyramid_reconstruct(Rb);
fusion = cat(3, R, G, B);
%% Final Output
%imshow([im2, fusion],'parent',app.UIAxes);
imshow([im2, final_output_rgb],'parent',app.UIAxes);
imwrite(fusion, 'enhanced.jpg')
imwrite(final_output_rgb, 'enhanced_final_refined.jpg');
%%
function uiqm_score = uiqm(img)
% COMPUTE_UIQM Computes the Underwater Image Quality Measure (UIQM)
% based on colorfulness (UICM), sharpness (UISM), and contrast (UIConM).
% 
% Input:
%   img - RGB underwater image (uint8 or double)
% Output:
%   uiqm_score - scalar UIQM score

% Check input
if size(img, 3) ~= 3
    error('Input image must be RGB.');
end

% Convert to double precision if needed
if ~isfloat(img)
    img = im2double(img);
end

% Compute subcomponents
uicm = compute_uicm(img);
uism = compute_uism(img);
uiconm = compute_uiconm(img);

% Standard weights (Panetta et al. 2015)
c1 = 0.0282;
c2 = 0.2953;
c3 = 3.5753;

% Final UIQM score
uiqm_score = c1 * uicm + c2 * uism + c3 * uiconm;

end

% -----------------------------
function uicm = compute_uicm(img)
% UICM: Underwater Image Colorfulness Measure

R = img(:,:,1);
G = img(:,:,2);
B = img(:,:,3);

RG = R - G;
YB = 0.5 * (R + G) - B;

meanRG = mean(RG(:));
meanYB = mean(YB(:));
stdRG = std(RG(:));
stdYB = std(YB(:));

uicm = -0.0268 * sqrt(meanRG^2 + meanYB^2) + 0.1586 * sqrt(stdRG^2 + stdYB^2);
end

% -----------------------------
function uism = compute_uism(img)
% UISM: Underwater Image Sharpness Measure

gray = rgb2gray(img);

% Sobel edge detection
sobel_h = fspecial('sobel')';
sobel_v = fspecial('sobel');

grad_h = imfilter(gray, sobel_h, 'replicate');
grad_v = imfilter(gray, sobel_v, 'replicate');

grad_mag = sqrt(grad_h.^2 + grad_v.^2);
uism = mean(grad_mag(:));
end

% -----------------------------
function uiconm = compute_uiconm(img)
% UIConM: Underwater Image Contrast Measure

gray = rgb2gray(img);

% RMS contrast
mean_val = mean(gray(:));
rms_contrast = sqrt(mean((gray(:) - mean_val).^2));
uiconm = rms_contrast;
end
function fsim_score = fsim_color(I1, I2)
% fsim_color - Computes FSIM for RGB images from scratch
%
% Usage:
%   score = fsim_color(I1, I2)
%
% Inputs:
%   I1 - Reference color image (RGB)
%   I2 - Enhanced/distorted color image (RGB)
%
% Output:
%   fsim_score - FSIM similarity score between 0 and 1

% Ensure double and normalized
if ~isa(I1, 'double'), I1 = im2double(I1); end
if ~isa(I2, 'double'), I2 = im2double(I2); end

assert(all(size(I1) == size(I2)), 'Images must be same size and RGB');

% Process each channel
fsim_R = fsim_channel(I1(:,:,1), I2(:,:,1));
fsim_G = fsim_channel(I1(:,:,2), I2(:,:,2));
fsim_B = fsim_channel(I1(:,:,3), I2(:,:,3));

% Final FSIM score (average)
fsim_score = mean([fsim_R, fsim_G, fsim_B]);

end

% -----------------------------------------------
% FSIM for one grayscale channel
% -----------------------------------------------
function score = fsim_channel(I1, I2)
T1 = 0.85;
T2 = 160;

% Phase Congruency (approximate using Gabor)
PC1 = phase_congruency_approx(I1);
PC2 = phase_congruency_approx(I2);

% Gradient Magnitude
GM1 = gradient_magnitude(I1);
GM2 = gradient_magnitude(I2);

% Similarity measures
S_pc = (2 .* PC1 .* PC2 + T1) ./ (PC1.^2 + PC2.^2 + T1);
S_gm = (2 .* GM1 .* GM2 + T2) ./ (GM1.^2 + GM2.^2 + T2);
Sim = S_pc .* S_gm;

% Weighting with max PC
PC_max = max(PC1, PC2);
score = sum(Sim(:) .* PC_max(:)) / sum(PC_max(:));
end

% -----------------------------------------------
% Compute Gradient Magnitude
% -----------------------------------------------
function GM = gradient_magnitude(I)
[Gx, Gy] = gradient(I);
GM = sqrt(Gx.^2 + Gy.^2);
end

% -----------------------------------------------
% Approximate Phase Congruency (Gabor-based)
% -----------------------------------------------
function PC = phase_congruency_approx(I)
% Create a Gabor filter bank approximation (4 orientations)
orientations = 4;
PC = zeros(size(I));
for i = 1:orientations
    theta = (i - 1) * pi / orientations;
    h = gabor_filter(size(I), theta);
    response = abs(imfilter(I, h, 'replicate'));
    PC = max(PC, response);
end
% Normalize
PC = PC / max(PC(:) + eps);
end

% -----------------------------------------------
% Gabor Filter Generator (frequency = 0.2, sigma = 2)
% -----------------------------------------------
function h = gabor_filter(sz, theta)
lambda = 5;
sigma = 2;
gamma = 0.5;
psi = 0;

[x, y] = meshgrid(-floor(sz(2)/2):floor(sz(2)/2)-1, ...
                  -floor(sz(1)/2):floor(sz(1)/2)-1);

x_theta = x * cos(theta) + y * sin(theta);
y_theta = -x * sin(theta) + y * cos(theta);

h = exp(-0.5 * (x_theta.^2 + gamma^2 * y_theta.^2) / sigma^2) ...
    .* cos(2 * pi * x_theta / lambda + psi);
end
%%
function Psnr = psnrxx(data2,output)
[m0,n0]=size(output);
[m1,n1]=size(data2);
 m=min(m0,m1);
 n=min(n0,n1);
% x=0;
 z=m*n;
for h=1:m
 for j=1:n
   %x=x+(double(data2(h,j))-double(output(h,j)))^2;
   x=(double(data2(h,j))-double(output(h,j)))^2;
 end
end
mse=x/z;
maxium=255*255;
Psnr=10*log10(maxium/mse);
end

%% Evaluation Metrics
UIQM_value = uiqm(final_output_rgb);
PSNR_value = psnrxx('Reference_1.png', 'enhanced_final_refined.jpg');
I1 = imread('Reference_1.png');
I2 = imread('enhanced_final_refined.jpg');
FSIM_value = fsim_color(I2, I1);
outputText = sprintf(['PSNR: %.4f dB\n'...
                      'UIQM: %.4f\nFSIM: %.4f'], ...
                      PSNR_value,UIQM_value,FSIM_value);
app.OutputTextArea.Value = splitlines(outputText);  % Convert \n to lines for display

        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mp4;*.avi', 'Video Files (*.mp4, *.avi)'});
            if isequal(filename, 0)
                return;
            end
            fullVideoPath = fullfile(pathname, filename);
            app.VideoObject = VideoReader(fullVideoPath);
            app.LoadedVideoPath = fullVideoPath;      
            frame = readFrame(app.VideoObject);
            imshow(frame, 'Parent', app.UIAxes3);
            app.VideoObject.CurrentTime = 0;
        
            % Show a message in the app
            app.TextArea_2.Value = {'Video loaded successfully.', ...
                      ['Filename: ', filename], ...
                      ['Duration: ', num2str(app.VideoObject.Duration), ' sec'], ...
                      ['Frame Rate: ', num2str(app.VideoObject.FrameRate), ' fps']};
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
     if isempty(app.VideoObject)
        uialert(app.UIFigure, 'Please load a video first.', 'No Video');
        return;
    end
    app.IsPlaying = true;
    % Rewind to beginning
    app.VideoObject.CurrentTime = 0;
    % Play loop
    while hasFrame(app.VideoObject) && app.IsPlaying
        frame = readFrame(app.VideoObject);
        % (Optional) Apply your enhancement function here
        % frame = yourEnhancementFunction(frame);
        imshow(frame, 'Parent', app.UIAxes3);
        pause(1 / app.VideoObject.FrameRate);  % Simulate real-time playback
    end
        end

        % Button pushed function: StopVideoButton
        function StopVideoButtonPushed(app, event)
            app.IsPlaying = false;
        end

        % Button pushed function: EnhanceVideoButton
        function EnhanceVideoButtonPushed(app, event)
        if isempty(app.VideoObject)
            app.TextArea_2.Value = "No video loaded!";
            return;
        end
        % Initialize video reader and writer
        vr = VideoReader(app.LoadedVideoPath);
        vw = VideoWriter('enhanced_video_sampled.mp4', 'MPEG-4');
        vw.FrameRate = vr.FrameRate;
        open(vw)
        %---------------------------------------------------------
        function sm = saliency_detection(img)
        %
        %---------------------------------------------------------
        % Read image and blur it with a 3x3 or 5x5 Gaussian filter
        %---------------------------------------------------------
        %img = imread('input_image.jpg');%Provide input image path
        gfrgb = imfilter(img, fspecial('gaussian', 3, 3), 'symmetric', 'conv');
        %---------------------------------------------------------
        % Perform sRGB to CIE Lab color space conversion (using D65)
        %---------------------------------------------------------
        %cform = makecform('srgb2lab', 'whitepoint', whitepoint('d65'));
        cform = makecform('srgb2lab');
        lab = applycform(gfrgb,cform);
        %---------------------------------------------------------
        % Compute Lab average values (note that in the paper this
        % average is found from the unblurred original image, but
        % the results are quite similar)
        %---------------------------------------------------------
        l = double(lab(:,:,1)); lm = mean(mean(l));
        a = double(lab(:,:,2)); am = mean(mean(a));
        b = double(lab(:,:,3)); bm = mean(mean(b));
        %---------------------------------------------------------
        % Finally compute the saliency map and display it.
        %---------------------------------------------------------
        sm = (l-lm).^2 + (a-am).^2 + (b-bm).^2;
        %imshow(sm,[]);
        end
        
        function out = gaussian_pyramid(img, level)
        h = 1/16* [1, 4, 6, 4, 1];
        filt = h'*h;
        out{1} = imfilter(img, filt, 'replicate', 'conv');
        temp_img = img;
        for i = 2 : level
            temp_img = temp_img(1 : 2 : end, 1 : 2 : end);
            out{i} = imfilter(temp_img, filt, 'replicate', 'conv');
        end
        end

        function out = laplacian_pyramid(img, level)
        h = 1/16* [1, 4, 6, 4, 1];
        %filt = h'*h;
        out{1} = img;
        temp_img = img;
        for i = 2 : level
            temp_img = temp_img(1 : 2 : end, 1 : 2 : end);
            %out{i} = imfilter(temp_img, filt, 'replicate', 'conv');
            out{i} = temp_img;
        end
        % calculate the DoG
        for i = 1 : level - 1
            [m, n] = size(out{i});
            out{i} = out{i} - imresize(out{i+1}, [m, n]);
        end
        end

        function out = pyramid_reconstruct(pyramid)
        level = length(pyramid);
        for i = level : -1 : 2
            %temp_pyramid = pyramid{i};
            [m, n] = size(pyramid{i - 1});
            %out = pyramid{i - 1} + imresize(temp_pyramid, [m, n]);
            pyramid{i - 1} = pyramid{i - 1} + imresize(pyramid{i}, [m, n]);
        end
        out = pyramid{1};
        end

    %% Process each frame
    while hasFrame(vr)
        % Read current frame
        rgbImage = double(readFrame(vr)) / 255;

        % Validate input
        if size(rgbImage, 3) ~= 3
            error('Frame must be RGB.');
        end
        %% Step 1: Category Detection and Dehazing
        % Detect dominant color and brightness for category
        avg_rgb = mean(mean(rgbImage, 1), 2);
        brightness = mean(rgb2gray(rgbImage), 'all');
        if avg_rgb(3) > avg_rgb(2) && avg_rgb(3) > avg_rgb(1) % Blue dominates
            if brightness < 0.3
                category = 'Low-Light Bluish';
            else
                category = 'Bluish Hazy';
            end
            elseif avg_rgb(2) > avg_rgb(3) && avg_rgb(2) > avg_rgb(1) % Green dominates
            if brightness < 0.3
                category = 'Low-Light Greenish';
            else
                category = 'Greenish Hazy';
            end
        else
            category = 'Greenish Hazy'; % Default
        end
        app.TextArea_3.Value = sprintf('Category detected: %s\n', category);
        % Dehazing with Dark Channel Prior
        dark_channel = min(rgbImage, [], 3);
        dark_channel = imfilter(dark_channel, ones(15,15)/225, 'replicate');
        transmission = 1 - 0.7 * dark_channel; % Adjusted for better detail preservation
        transmission = max(0.1, transmission); % Minimum transmission
        I_dehazed = zeros(size(rgbImage));
        for c = 1:3
            I_dehazed(:,:,c) = (rgbImage(:,:,c) - 0.1) ./ transmission;
        end
        I_dehazed = max(0, min(1, I_dehazed));
        fprintf('Dehazed range: [%.4f, %.4f]\n', min(I_dehazed(:)), max(I_dehazed(:)));
        %% Split channels for white balance
        Ir = I_dehazed(:,:,1);
        Ig = I_dehazed(:,:,2);
        Ib = I_dehazed(:,:,3);

        Ir_mean = mean(Ir, 'all');
        Ig_mean = mean(Ig, 'all');
        Ib_mean = mean(Ib, 'all');
        %% Color compensation with category tuning
        alpha = 0.1;
        switch category
            case 'Bluish Hazy'
                Irc = Ir + alpha * (Ig_mean - Ir_mean); % Boost red indirectly
                Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
            case 'Greenish Hazy'
                Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.2; % Boost red
                Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
            case 'Low-Light Bluish'
                Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.5; % Boost illumination
                Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
            case 'Low-Light Greenish'
                Irc = Ir + alpha * (Ig_mean - Ir_mean) * 1.5; % Boost illumination
                Ibc = Ib + 0 * (Ig_mean - Ib_mean);     % No blue compensation
        end
        %% White Balance
        I = cat(3, Irc, Ig, Ibc);
        I_lin = rgb2lin(I);
        percentiles = 5;
        illuminant = illumgray(I_lin, percentiles);
        I_lin = chromadapt(I_lin, illuminant, 'ColorSpace', 'linear-rgb');
        Iwb = lin2rgb(I_lin);

        %% Gamma Correction
        gamma = 1.5; % Adjusted for better SSIM alignment
        Igamma = imadjust(Iwb, [], [], gamma);
        %% Image Sharpening with Category Tuning
        sigma = 20;
        Igauss = Iwb;
        N = 30;
        for iter = 1:N
            Igauss = imgaussfilt(Igauss, sigma);
            Igauss = min(Iwb, Igauss);
        end
        gain = 1;
        switch category
            case {'Bluish Hazy', 'Greenish Hazy'}
                gain = 0.8; % Reduced gain for hazy images
            case {'Low-Light Bluish', 'Low-Light Greenish'}
                gain = 1.0; % Adjusted gain for low-light images
        end
        Norm = (Iwb - gain * Igauss);
        for n = 1:3
            Norm(:,:,n) = histeq(Norm(:,:,n));
        end
        Isharp = (Iwb + Norm) / 2;
        %% Attention Mechanism
        WS1 = saliency_detection(Isharp);
        WS1 = WS1 / max(WS1, [], 'all');
        WS2 = saliency_detection(Igamma);
        WS2 = WS2 / max(WS2, [], 'all');

        Isharp_lab = rgb2lab(Isharp);
        Igamma_lab = rgb2lab(Igamma);

        R1 = double(Isharp_lab(:, :, 1)) / 255;
        WC1 = sqrt((((Isharp(:,:,1)) - R1).^2 + ((Isharp(:,:,2)) - R1).^2 + ((Isharp(:,:,3)) - R1).^2) / 3);
        WSAT1 = sqrt(1/3 * ((Isharp_lab(:,:,2)).^2 + (Isharp_lab(:,:,3)).^2));
        WSAT1 = WSAT1 / max(WSAT1, [], 'all');

        R2 = double(Igamma_lab(:, :, 1)) / 255;
        WC2 = sqrt((((Igamma(:,:,1)) - R2).^2 + ((Igamma(:,:,2)) - R2).^2 + ((Igamma(:,:,3)) - R2).^2) / 3);
        WSAT2 = sqrt(1/3 * ((Igamma_lab(:,:,2)).^2 + (Igamma_lab(:,:,3)).^2));
        WSAT2 = WSAT2 / max(WSAT2, [], 'all');
        attention1 = WS1 .* WC1 .* WSAT1;
        attention2 = WS2 .* WC2 .* WSAT2;
        attention1 = attention1 / max(attention1, [], 'all');
        attention2 = attention2 / max(attention2, [], 'all');

        W1 = (WC1 + WS1 + WSAT1 + attention1 + 0.1) ./ (WC1 + WS1 + WSAT1 + attention1 + WC2 + WS2 + WSAT2 + attention2 + 0.2);
        W2 = (WC2 + WS2 + WSAT2 + attention2 + 0.1) ./ (WC1 + WS1 + WSAT1 + attention1 + WC2 + WS2 + WSAT2 + attention2 + 0.2);

        %% Multi-Scale Fusion
        img1 = Isharp;
        img2 = Igamma;
        level = 10;
        Weight1 = gaussian_pyramid(W1, level);
        Weight2 = gaussian_pyramid(W2, level);

        R1 = laplacian_pyramid(img1(:,:,1), level);
        G1 = laplacian_pyramid(img1(:,:,2), level);
        B1 = laplacian_pyramid(img1(:,:,3), level);

        R2 = laplacian_pyramid(img2(:,:,1), level);
        G2 = laplacian_pyramid(img2(:,:,2), level);
        B2 = laplacian_pyramid(img2(:,:,3), level);
        for k = 1:level
            Rr{k} = Weight1{k} .* R1{k} + Weight2{k} .* R2{k};
            Rg{k} = Weight1{k} .* G1{k} + Weight2{k} .* G2{k};
            Rb{k} = Weight1{k} .* B1{k} + Weight2{k} .* B2{k};
        end

        R = pyramid_reconstruct(Rr);
        G = pyramid_reconstruct(Rg);
        B = pyramid_reconstruct(Rb);
        fusion = cat(3, R, G, B);
        fusion = max(0, min(1, fusion));

        fusion = imadjust(fusion, [min(fusion(:)); max(fusion(:))], [0; 1]);
         %% Refined Color Enhancement
        fusion_hsv = rgb2hsv(fusion);
        saturation_boost = 1.05;
        sat_mask = fusion_hsv(:,:,2) < 0.7;
        fusion_hsv(:,:,2) = fusion_hsv(:,:,2) .* (1 + saturation_boost * sat_mask);
        fusion_hsv(:,:,2) = min(fusion_hsv(:,:,2), 1);
        fusion = hsv2rgb(fusion_hsv);

        mean_rgb = mean(mean(fusion, 1), 2);
        color_adjust = 0.01;
        fusion(:,:,1) = fusion(:,:,1) + color_adjust * (mean_rgb(2) - mean_rgb(1));
        fusion(:,:,2) = fusion(:,:,2) + color_adjust * (mean_rgb(3) - mean_rgb(2));
        fusion(:,:,3) = fusion(:,:,3) + color_adjust * (mean_rgb(1) - mean_rgb(3));
        fusion = max(0, min(1, fusion));
        %% Improved Contrast Enhancement with Enhanced Noise Management
        fusion = imgaussfilt(fusion, 0.7);
        fusion_hsv = rgb2hsv(fusion);
        h = fusion_hsv(:,:,1);
        S = fusion_hsv(:,:,2);
        V = fusion_hsv(:,:,3);

        H = [0 -0.25 0; -0.25 1 -0.25; 0 -0.25 0];
        filter1 = imfilter(V, H, 'replicate');
        filter2 = imfilter(S, H, 'replicate');
        V_diff = V - filter1;
        S_diff = S - filter2;
        window_size = 3;
        varience_value = zeros(size(V));
        varience_saturation = zeros(size(S));
        for i = 1:size(V, 1) - window_size + 1
            for j = 1:size(V, 2) - window_size + 1
                V_window = V_diff(i:i+window_size-1, j:j+window_size-1);
                S_window = S_diff(i:i+window_size-1, j:j+window_size-1);
                varience_value(i,j) = mean(V_window(:).^2);
                varience_saturation(i,j) = mean(S_window(:).^2);
            end
        end
        varience_value(end-window_size+2:end, :) = repmat(varience_value(end-window_size+1, :), window_size-1, 1);
        varience_value(:, end-window_size+2:end) = repmat(varience_value(:, end-window_size+1), 1, window_size-1);
        varience_saturation(end-window_size+2:end, :) = repmat(varience_saturation(end-window_size+1, :), window_size-1, 1);
        varience_saturation(:, end-window_size+2:end) = repmat(varience_saturation(:, end-window_size+1), 1, window_size-1);
        try1 = V - varience_value;
        try2 = S - varience_saturation;
        epsilon = 1e-10;
        Correlation = (try1 .* try2) ./ sqrt((varience_value + epsilon) .* (varience_saturation + epsilon));
        mean_V = mean(V(:));
        std_V = std(V(:));
        k1 = 0.2 + (1 - mean_V) * 0.1;
        k2 = 0.1 + (std_V / 4);
        fprintf('Adaptive k1: %.2f, k2: %.2f\n', k1, k2);

        V_enhancement = V + k1 .* try1 - (k2 .* try2) .* Correlation;
        S_enhancement = S + (k1 / 4) .* try2;
        V_enhancement = max(0, min(1, V_enhancement));
        S_enhancement = max(0, min(1, S_enhancement));
        num_tiles = [3 3];
        clip_limit = 0.003 + (1 - mean_V) * 0.005;
        V_clahe = adapthisteq(V_enhancement, 'NumTiles', num_tiles, 'ClipLimit', clip_limit);

        mean_V_clahe = mean(V_clahe(:));
        gamma_contrast = 0.95 + (1 - mean_V_clahe) * 0.05;
        contrast_stretching = V_clahe .^ gamma_contrast;
        fprintf('Adaptive gamma for contrast: %.2f\n', gamma_contrast);
        final_output = cat(3, h, S_enhancement, contrast_stretching);
        final_output_rgb = hsv2rgb(final_output); 
    end
        %% Write enhanced frame to video and display
        writeVideo(vw, im2uint8(final_output_rgb));
        imshow(final_output_rgb, 'Parent', app.UIAxes5);
        drawnow; % Update the display for each frame
     %% Close video writer
    close(vw);
        end

        % Button pushed function: PlayVideoButton_2
        function PlayVideoButton_2Pushed(app, event)
            app.IsPlaying = true; % Reset stop flag
            videoFile = 'enhanced_video_sampled.mp4'; 
            if ~isfile(videoFile)
                uialert(app.UIFigure, 'Enhanced video file not found.', 'Error');
                return;
            end
            vr = VideoReader(videoFile);   
            while hasFrame(vr) && app.IsPlaying
                frame = readFrame(vr);
                imshow(frame, 'Parent', app.UIAxes5);
                pause(1 / vr.FrameRate);
            end
        end

        % Button pushed function: StopVideoButton_2
        function StopVideoButton_2Pushed(app, event)
            app.IsPlaying = false;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Enhanced Image')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Loaded Video')
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTickLabel = '';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Enhanced Video frame')
            app.UIAxes5.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTickLabel = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Attention-Driven Dehazing and Fusion for Category-Specific Underwater Image/Video Enhancement';
            app.LoadedimageLabel = uilabel(app.UIFigure);
            app.LoadedimageLabel.Text = 'Loaded image';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.UnderwaterImageenhancementLabel = uilabel(app.UIFigure);
            app.UnderwaterImageenhancementLabel.Text = 'Underwater Image enhancement';
            app.UnderwaterVideoEnhancementLabel = uilabel(app.UIFigure);
            app.UnderwaterVideoEnhancementLabel.Text = 'Underwater Video Enhancement';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image ';
            app.EnhanceImageButton = uibutton(app.UIFigure, 'push');
            app.EnhanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceImageButtonPushed, true);
            app.EnhanceImageButton.Text = 'Enhance Image';
            app.TextArea = uitextarea(app.UIFigure);
            app.clickenhanceimageandwaitforprocessLabel = uilabel(app.UIFigure);
            app.clickenhanceimageandwaitforprocessLabel.Text = 'click enhance image and wait for process';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.ImageperformancemetricsLabel = uilabel(app.UIFigure);
            app.ImageperformancemetricsLabel.Text = 'Image performance metrics';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.videoperformancemetricsLabel = uilabel(app.UIFigure);
            app.videoperformancemetricsLabel.Text = 'video performance metrics';
            app.PlayVideoButton = uibutton(app.UIFigure, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Icon = fullfile(pathToMLAPP, 'play_icon.png');
            app.PlayVideoButton.Text = 'Play Video';
            app.StopVideoButton = uibutton(app.UIFigure, 'push');
            app.StopVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StopVideoButtonPushed, true);
            app.StopVideoButton.Icon = fullfile(pathToMLAPP, 'stop_icon.png');
            app.StopVideoButton.Text = 'Stop Video';
            app.EnhanceVideoButton = uibutton(app.UIFigure, 'push');
            app.EnhanceVideoButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceVideoButtonPushed, true);
            app.EnhanceVideoButton.Text = 'Enhance Video';
            app.PlayVideoButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayVideoButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButton_2Pushed, true);
            app.PlayVideoButton_2.Icon = fullfile(pathToMLAPP, 'play_icon.png');
            app.PlayVideoButton_2.Text = 'Play Video';
            app.StopVideoButton_2 = uibutton(app.UIFigure, 'push');
            app.StopVideoButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopVideoButton_2Pushed, true);
            app.StopVideoButton_2.Icon = fullfile(pathToMLAPP, 'stop_icon.png');
            app.StopVideoButton_2.Text = 'Stop Video';
            app.clickenhanceimageandwaitforprocessLabel_2 = uilabel(app.UIFigure);
            app.clickenhanceimageandwaitforprocessLabel_2.Text = 'click enhance image and wait for process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end